classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoggingLamp                     matlab.ui.control.Lamp
        LoggingLampLabel                matlab.ui.control.Label
        DataLoggingSwitch               matlab.ui.control.Switch
        DataLoggingSwitchLabel          matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        DistancetoGoalGauge             matlab.ui.control.LinearGauge
        DistancetoGoalGaugeLabel        matlab.ui.control.Label
        ObstacleAlertLamp               matlab.ui.control.Lamp
        ObstacleAlertLampLabel          matlab.ui.control.Label
        CurrentVelocityGauge            matlab.ui.control.Gauge
        CurrentVelocityGaugeLabel       matlab.ui.control.Label
        Nav2ParameterConfigurationPanel  matlab.ui.container.Panel
        LetsGoForwardButton             matlab.ui.control.Button
        MaxAccEditField                 matlab.ui.control.NumericEditField
        MaxAccelerationms2Label         matlab.ui.control.Label
        GenerateYAMLBtn                 matlab.ui.control.Button
        GoalToleranceEditField          matlab.ui.control.NumericEditField
        GoalTolerancemEditFieldLabel    matlab.ui.control.Label
        MinSafeDistanceEditField        matlab.ui.control.NumericEditField
        MinSafeDistancemEditFieldLabel  matlab.ui.control.Label
        BrakingReactionTimeEditField    matlab.ui.control.NumericEditField
        BrakingReactionTimesEditFieldLabel  matlab.ui.control.Label
        MaxVelEditField                 matlab.ui.control.NumericEditField
        MaxVelocitymsEditFieldLabel     matlab.ui.control.Label
        WaypointDefinitionPanel         matlab.ui.container.Panel
        WaypointTable                   matlab.ui.control.Table
        ImportCSVGPSButton              matlab.ui.control.Button
        AddWaypointButton_3             matlab.ui.control.Button
        orientationEditField            matlab.ui.control.NumericEditField
        orientationdegEditFieldLabel    matlab.ui.control.Label
        ymEditField                     matlab.ui.control.NumericEditField
        ymEditFieldLabel                matlab.ui.control.Label
        xmEditField                     matlab.ui.control.NumericEditField
        xmEditFieldLabel                matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    RobotSub % ROS 2 abonesi
        LogData = [] % Zaman, Hız ve Konum verileri
        IsLogging = false % Kayıt durumu
        ROSNode % MATLAB'ın ağdaki kimliği
        VelPub  % (Artık sadece acil durum veya test için)
        VelMsg  
        GoalPub % Nav2 Hedef Gönderici
        GoalMsg % Nav2 Hedef Mesajı
        targetX % Tıklanan X koordinatı
        targetY % Tıklanan Y koordinatı
        RobotPlot % GERÇEK ZAMANLI KONUM ÇİZİM OBJESİ
    end
    
    methods (Access = private)
        
        function updateDashboard(app, msg)
            % 1. Odometri mesajından HIZ ve KONUM verilerini çek
            current_vel = msg.twist.twist.linear.x;
            current_x = msg.pose.pose.position.x;
            current_y = msg.pose.pose.position.y;
            
            % 2. Kadranı güncelle
            app.CurrentVelocityGauge.Value = abs(current_vel);
            
            % 3. Engel Uyarı Lambasını güncelle
            if abs(current_vel) < 0.01
                app.ObstacleAlertLamp.Color = 'red';
            else
                app.ObstacleAlertLamp.Color = 'green';
            end
            
            % 4. HEDEFE UZAKLIK (Distance to Goal) HESAPLAMASI
            if ~isempty(app.targetX) && ~isempty(app.targetY)
                % Öklid mesafesi formülü: sqrt((x2-x1)^2 + (y2-y1)^2)
                dist = sqrt((app.targetX - current_x)^2 + (app.targetY - current_y)^2);
                app.DistancetoGoalGauge.Value = dist;
            end
            
            % 5. GERÇEK ZAMANLI KONUM ÇİZİMİ
            % Eğer plot objesi henüz oluşturulmadıysa oluştur, varsa sadece X-Y verisini güncelle
            if isempty(app.RobotPlot) || ~isvalid(app.RobotPlot)
                hold(app.UIAxes, 'on');
                % Robotu temsil eden mavi dolgulu bir yuvarlak çiz
                app.RobotPlot = plot(app.UIAxes, current_x, current_y, 'bo', 'MarkerFaceColor', 'b', 'MarkerSize', 8);
                hold(app.UIAxes, 'off');
            else
                app.RobotPlot.XData = current_x;
                app.RobotPlot.YData = current_y;
            end
            
            % 6. VERİ KAYDI (Logging)
            if app.IsLogging
                timestamp = posixtime(datetime('now'));
                app.LogData = [app.LogData; timestamp, current_vel];
            end
        end
    end % <--- İŞTE EKSİK OLAN BU! METHODS BLOĞUNU KAPATIR

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    try
        % 1. MATLAB'ı ROS 2 ağına bir "Düğüm" (Node) olarak kaydet
        app.ROSNode = ros2node('/zeynep_matlab_ui');
        
        % 2. Sensör Verisi Dinleyici (Subscriber) Kurulumu
       app.RobotSub = ros2subscriber(app.ROSNode, '/odom', 'nav_msgs/Odometry', @app.updateDashboard);
        % 3. Komut Gönderici (Publisher) Kurulumu
        app.VelPub = ros2publisher(app.ROSNode, '/cmd_vel', 'geometry_msgs/Twist');
        app.VelMsg = ros2message(app.VelPub); % Boş bir mesaj taslağı oluştur
        app.GoalPub = ros2publisher(app.ROSNode, '/goal_pose', 'geometry_msgs/PoseStamped');
        app.GoalMsg = ros2message(app.GoalPub);
    catch ME
        % Eğer Necati'nin ROS 2 ağı kapalıysa arayüzün çökmesini engelle
        uialert(app.UIFigure, ['ROS 2 ağı bulunamadı (Necati simülasyonu henüz açmamış olabilir). Arayüz çevrimdışı modda başlatılıyor. Hata detayı: ', ME.message], 'Bağlantı Bekleniyor');
    end
        end

        % Button pushed function: AddWaypointButton_3
        function AddWaypointButton_3Pushed(app, event)
            % 1. Kutulardaki değerleri al
x_val = app.xmEditField.Value;
y_val = app.ymEditField.Value;
theta_val = app.orientationEditField.Value;

% 2. Tablodaki mevcut veriyi çek
current_data = app.WaypointTable.Data;

% 3. Yeni eklenecek satırı oluştur
new_row = [x_val, y_val, theta_val];

% 4. Veriyi tabloya ekle (Tablo boş mu dolu mu kontrol et)
if isempty(current_data)
    app.WaypointTable.Data = new_row;
else
    app.WaypointTable.Data = [current_data; new_row];
end

% 5. (Kullanıcı Dostu Özellik) Eklendikten sonra kutuları sıfırla
app.xmEditField.Value = 0;
app.ymEditField.Value = 0;
app.orientationEditField.Value = 0;

% --- HARİTADA GÖRSELLEŞTİRME KISMI ---
% Tablodaki tüm X ve Y verilerini çek
x_data = app.WaypointTable.Data(:, 1);
y_data = app.WaypointTable.Data(:, 2);

% UIAxes üzerinde bu noktaları çizgi (- ) ve nokta (o) şeklinde çizdir
plot(app.UIAxes, x_data, y_data, '-o', 'LineWidth', 2, 'MarkerSize', 6, 'MarkerFaceColor', 'r', 'Color', 'b');

% Haritaya ızgara ekle ve başlık koy (Hocalar grid sever)
grid(app.UIAxes, 'on');
title(app.UIAxes, 'Planlanan Navigasyon Rotası');
xlabel(app.UIAxes, 'X Konumu (m)');
ylabel(app.UIAxes, 'Y Konumu (m)');
        end

        % Button pushed function: ImportCSVGPSButton
        function ImportCSVGPSButtonPushed(app, event)
            % 1. Kullanıcıya dosya seçme penceresi aç (Sadece .csv dosyalarını göster)
[file, path] = uigetfile('*.csv', 'Koordinat Dosyasını Seçin');

% 2. Eğer kullanıcı iptal tuşuna basarsa hiçbir şey yapma
if isequal(file, 0)
    return; 
end

% 3. Dosyanın tam yolunu oluştur ve okumaya çalış
fullpath = fullfile(path, file);
try
    % Dosyadaki verileri matris olarak oku
try
    imported_data = readmatrix(fullpath);
    
    % Dosya içeriği sayısal mı kontrol et
    if isempty(imported_data) || ~isnumeric(imported_data)
        error('Dosya içeriği boş veya geçersiz formatta.');
    end
    
    % Sütun sayısı kontrolü (En az X ve Y olmalı)
    if size(imported_data, 2) < 2
        error('Dosyada en az 2 sütun (X ve Y) bulunmalıdır.');
    end
    
    % Eğer kod buraya kadar geldiyse veriyi tabloya bas
    app.WaypointTable.Data = imported_data; 
    
catch ME
    % Kullanıcıya hatayı şık bir pencereyle göster
    uialert(app.UIFigure, ['Dosya okunamadı: ' ME.message], 'Dosya Hatası', 'Icon', 'error');
end
    
    % Gelen veride en az 2 sütun (X ve Y) olduğunu varsayıyoruz
    if size(imported_data, 2) >= 3
        new_waypoints = imported_data(:, 1:3); % X, Y, Theta
    else
        % Eğer sadece X ve Y varsa, Theta'yı otomatik 0 yap
        new_waypoints = [imported_data(:, 1:2), zeros(size(imported_data, 1), 1)];
    end
    
    % 4. Tabloya veriyi ekle
    current_data = app.WaypointTable.Data;
    if isempty(current_data)
        app.WaypointTable.Data = new_waypoints;
    else
        app.WaypointTable.Data = [current_data; new_waypoints];
    end
    
    % 5. Haritayı (UIAxes) anında güncelle
    x_data = app.WaypointTable.Data(:, 1);
    y_data = app.WaypointTable.Data(:, 2);
    
    plot(app.UIAxes, x_data, y_data, '-o', 'LineWidth', 2, 'MarkerSize', 6, 'MarkerFaceColor', 'r', 'Color', 'b');
    grid(app.UIAxes, 'on');
    title(app.UIAxes, 'Planlanan Navigasyon Rotası');
    xlabel(app.UIAxes, 'X Konumu (m)');
    ylabel(app.UIAxes, 'Y Konumu (m)');

catch ME
    % Eğer dosya bozuksa veya yanlış formatta ise hata mesajı göster (Çok havalı bir detay)
    uialert(app.UIFigure, 'Dosya okunamadı. Lütfen içeriği sayılardan oluşan bir CSV dosyası seçin.', 'Dosya Hatası');
end
        end

        % Button pushed function: GenerateYAMLBtn
        function GenerateYAMLBtnButtonPushed(app, event)
            % 1. Tablodaki rotayı (Waypoint'leri) al
waypoints = app.WaypointTable.Data;

% --- GÜVENLİK KONTROLLERİ (BULLETPROOF) ---
% 1. Rota Boş mu?
if isempty(app.WaypointTable.Data)
    uialert(app.UIFigure, 'Henüz bir rota oluşturmadınız. Lütfen önce manuel nokta ekleyin veya CSV yükleyin.', ...
        'Eksik Rota Verisi', 'Icon', 'warning');
    return;
end

% 2. Hız ve İvme Değerleri Mantıklı mı?
if app.MaxVelEditField.Value <= 0 || app.MaxAccEditField.Value <= 0
    uialert(app.UIFigure, 'Maksimum hız ve ivme değerleri 0 veya negatif olamaz!', ...
        'Parametre Hatası', 'Icon', 'error');
    return;
end

% 3. Güvenli Mesafe ve Tolerans Uygun mu? (En az 0.1m olmalı)
if app.MinSafeDistanceEditField.Value < 0.1
    uialert(app.UIFigure, 'Minimum güvenli mesafe çok düşük (en az 0.1m olmalı)!', ...
        'Güvenlik Uyarısı', 'Icon', 'info');
    return;
end

% 2. Parametre kutularındaki değerleri çek
% NOT: Buradaki isimleri Component Browser'daki isimlerinle eşleştirmeyi unutma!
max_vel = app.MaxVelEditField.Value;
max_acc = app.MaxAccEditField.Value;
brak_time = app.BrakingReactionTimeEditField.Value;
safe_dist = app.MinSafeDistanceEditField.Value;
goal_tol = app.GoalToleranceEditField.Value;

% 3. Kullanıcıya dosyayı nereye kaydedeceğini sor
[file, path] = uiputfile('nav2_mission.yaml', 'Görev Dosyasını Kaydet');

% Eğer kullanıcı iptal derse işlemi durdur
if isequal(file, 0)
    return; 
end

% 4. Dosyayı oluştur ve içine YAML formatında yazdır
fullpath = fullfile(path, file);
fid = fopen(fullpath, 'w');

% Nav2 YAML Başlığı ve Parametreler
fprintf(fid, 'nav2_mission:\n');
fprintf(fid, '  ros__parameters:\n');
fprintf(fid, '    max_velocity: %.2f\n', max_vel);
fprintf(fid, '    max_acceleration: %.2f\n', max_acc);
fprintf(fid, '    braking_reaction_time: %.2f\n', brak_time);
fprintf(fid, '    min_safe_distance: %.2f\n', safe_dist);
fprintf(fid, '    goal_tolerance: %.2f\n', goal_tol);

% Waypoint listesini yazdırma
fprintf(fid, '    waypoints:\n');
for i = 1:size(waypoints, 1)
    fprintf(fid, '      - [%.2f, %.2f, %.2f]\n', waypoints(i,1), waypoints(i,2), waypoints(i,3));
end

fclose(fid);

% 5. Başarı mesajı göster
uialert(app.UIFigure, 'YAML görev dosyası başarıyla oluşturuldu! Artık ROS 2''de çalıştırılabilir.', 'Başarılı', 'Icon', 'success');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
% Her şeyi temizle
app.WaypointTable.Data = []; 
cla(app.UIAxes); 
grid(app.UIAxes, 'on');
title(app.UIAxes, 'Planlanan Navigasyon Rotası');

% Kutuları varsayılan değerlere döndür
app.xmEditField.Value = 0;
app.ymEditField.Value = 0;
app.orientationEditField.Value = 0;
        end

        % Value changed function: DataLoggingSwitch
        function DataLoggingSwitchValueChanged(app, event)
        % 1. Seçilen değeri al ('On' veya 'Off')
    value = app.DataLoggingSwitch.Value;
    
    if strcmp(value, 'On')
        app.IsLogging = true;
        app.LoggingLamp.Color = 'red'; % Kayıt ışığını yak
        app.LogData = []; % Yeni kayıt için listeyi sıfırla
        
    else
        app.IsLogging = false;
        app.LoggingLamp.Color = 'green'; % Kayıt ışığını söndür
        
        % Kayıt durduğunda veriyi kaydetme penceresini aç
        if ~isempty(app.LogData)
            uisave('app.LogData', 'robot_performans_verisi.mat');
        end
    end

        end

        % Button pushed function: LetsGoForwardButton
        function LetsGoForwardButtonPushed(app, event)
    % GÖREV BAŞLATICI (MISSION LAUNCHER) - Nav2'ye Hedef Gönderir
            
            % Eğer tablo boşsa uyarı ver
            if isempty(app.WaypointTable.Data)
                uialert(app.UIFigure, 'Hedef bulunamadı! Lütfen haritadan hedef seçin veya koordinat girin.', 'Görev Hatası', 'Icon', 'warning');
                return;
            end
            
            % Tablodaki en son eklenen noktayı hedef olarak al
            % (İstersen bir döngüyle sırayla da gönderebilirsin)
            sonSatir = size(app.WaypointTable.Data, 1);
            tx = app.WaypointTable.Data(sonSatir, 1);
            ty = app.WaypointTable.Data(sonSatir, 2);
            t_theta = app.WaypointTable.Data(sonSatir, 3);
            
            % ROS 2 Nav2 Hedef Mesajını Doldur
            app.GoalMsg.header.frame_id = 'map'; % Nav2 genellikle 'map' frame'ini referans alır
            app.GoalMsg.pose.position.x = tx;
            app.GoalMsg.pose.position.y = ty;
            
            % Açı (Derece) değerini ROS'un anladığı Quaternion'a çevirme (Z ekseninde dönüş)
            app.GoalMsg.pose.orientation.w = cos(deg2rad(t_theta)/2);
            app.GoalMsg.pose.orientation.z = sin(deg2rad(t_theta)/2);
            
            % Hedefi Nav2'ye gönder!
            send(app.GoalPub, app.GoalMsg);
            
            uialert(app.UIFigure, sprintf('Nav2 otonom sürüş başlatıldı!\nHedef: X=%.2f, Y=%.2f', tx, ty), 'Görev Gönderildi', 'Icon', 'success');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
   % 1. Tıklanan noktanın koordinatlarını al
            coordinates = event.IntersectionPoint;
            clickedX = double(coordinates(1)); 
            clickedY = double(coordinates(2)); 
            
            % 2. Değerleri hafızaya ve kutulara kaydet
            app.targetX = clickedX;
            app.targetY = clickedY;
            app.xmEditField.Value = clickedX; 
            app.ymEditField.Value = clickedY;
            
            % 3. Haritada kırmızı çarpı (hedef noktası) olarak işaretle
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, clickedX, clickedY, 'rx', 'MarkerSize', 10, 'LineWidth', 2);
            hold(app.UIAxes, 'off');
            
            % 4. OTOMATİK OLARAK TABLOYA EKLEME KISMI
            current_data = app.WaypointTable.Data;
            new_row = [clickedX, clickedY, 0]; % Tıklamada yönelim(theta) 0 varsayılır
            
            if isempty(current_data)
                app.WaypointTable.Data = new_row;
            else
                app.WaypointTable.Data = [current_data; new_row];
            end
            
            fprintf('Hedef eklendi: X=%.2f, Y=%.2f\n', clickedX, clickedY);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % 1. ROS 2 bağlantılarını temizle (Zombileri yok et)
            clear app.VelPub;
            clear app.GoalPub;
            clear app.RobotSub;
            clear app.ROSNode;
            
            % 2. Pencereyi güvenlice kapat
            delete(app);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.WaypointDefinitionPanel = uipanel(app.UIFigure);
            app.WaypointDefinitionPanel.Title = 'Waypoint Definition';
            app.xmEditFieldLabel = uilabel(app.WaypointDefinitionPanel);
            app.xmEditFieldLabel.Text = 'x(m)';
            app.xmEditField = uieditfield(app.WaypointDefinitionPanel, 'numeric');
            app.ymEditFieldLabel = uilabel(app.WaypointDefinitionPanel);
            app.ymEditFieldLabel.Text = 'y(m)';
            app.ymEditField = uieditfield(app.WaypointDefinitionPanel, 'numeric');
            app.orientationdegEditFieldLabel = uilabel(app.WaypointDefinitionPanel);
            app.orientationdegEditFieldLabel.Text = 'orientation (deg)';
            app.orientationEditField = uieditfield(app.WaypointDefinitionPanel, 'numeric');
            app.AddWaypointButton_3 = uibutton(app.WaypointDefinitionPanel, 'push');
            app.AddWaypointButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddWaypointButton_3Pushed, true);
            app.AddWaypointButton_3.Text = 'Add Waypoint';
            app.ImportCSVGPSButton = uibutton(app.WaypointDefinitionPanel, 'push');
            app.ImportCSVGPSButton.ButtonPushedFcn = createCallbackFcn(app, @ImportCSVGPSButtonPushed, true);
            app.ImportCSVGPSButton.Text = 'Import CSV/GPS';
            app.WaypointTable = uitable(app.WaypointDefinitionPanel);
            app.WaypointTable.ColumnName = {'x(m)'; 'y(m)'; 'orientation'};
            app.WaypointTable.RowName = {};
            app.Nav2ParameterConfigurationPanel = uipanel(app.UIFigure);
            app.Nav2ParameterConfigurationPanel.Title = 'Nav2 Parameter Configuration';
            app.MaxVelocitymsEditFieldLabel = uilabel(app.Nav2ParameterConfigurationPanel);
            app.MaxVelocitymsEditFieldLabel.Text = 'Max Velocity (m/s)';
            app.MaxVelEditField = uieditfield(app.Nav2ParameterConfigurationPanel, 'numeric');
            app.BrakingReactionTimesEditFieldLabel = uilabel(app.Nav2ParameterConfigurationPanel);
            app.BrakingReactionTimesEditFieldLabel.Text = 'Braking Reaction Time (s)';
            app.BrakingReactionTimeEditField = uieditfield(app.Nav2ParameterConfigurationPanel, 'numeric');
            app.MinSafeDistancemEditFieldLabel = uilabel(app.Nav2ParameterConfigurationPanel);
            app.MinSafeDistancemEditFieldLabel.Text = 'Min Safe Distance (m)';
            app.MinSafeDistanceEditField = uieditfield(app.Nav2ParameterConfigurationPanel, 'numeric');
            app.GoalTolerancemEditFieldLabel = uilabel(app.Nav2ParameterConfigurationPanel);
            app.GoalTolerancemEditFieldLabel.Text = 'Goal Tolerance (m)';
            app.GoalToleranceEditField = uieditfield(app.Nav2ParameterConfigurationPanel, 'numeric');
            app.GenerateYAMLBtn = uibutton(app.Nav2ParameterConfigurationPanel, 'push');
            app.GenerateYAMLBtn.ButtonPushedFcn = createCallbackFcn(app, @GenerateYAMLBtnButtonPushed, true);
            app.GenerateYAMLBtn.Text = 'Generate Nav2 YAML';
            app.MaxAccelerationms2Label = uilabel(app.Nav2ParameterConfigurationPanel);
            app.MaxAccelerationms2Label.Text = 'Max Acceleration (m/s^2)';
            app.MaxAccEditField = uieditfield(app.Nav2ParameterConfigurationPanel, 'numeric');
            app.LetsGoForwardButton = uibutton(app.Nav2ParameterConfigurationPanel, 'push');
            app.LetsGoForwardButton.ButtonPushedFcn = createCallbackFcn(app, @LetsGoForwardButtonPushed, true);
            app.LetsGoForwardButton.Text = 'Let''s Go Forward';
            app.CurrentVelocityGaugeLabel = uilabel(app.UIFigure);
            app.CurrentVelocityGaugeLabel.Text = 'Current Velocity';
            app.CurrentVelocityGauge = uigauge(app.UIFigure, 'circular');
            app.ObstacleAlertLampLabel = uilabel(app.UIFigure);
            app.ObstacleAlertLampLabel.Text = 'Obstacle Alert';
            app.ObstacleAlertLamp = uilamp(app.UIFigure);
            app.DistancetoGoalGaugeLabel = uilabel(app.UIFigure);
            app.DistancetoGoalGaugeLabel.Text = 'Distance to Goal';
            app.DistancetoGoalGauge = uigauge(app.UIFigure, 'linear');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DataLoggingSwitchLabel = uilabel(app.UIFigure);
            app.DataLoggingSwitchLabel.Text = 'Data Logging';
            app.DataLoggingSwitch = uiswitch(app.UIFigure, 'slider');
            app.DataLoggingSwitch.ValueChangedFcn = createCallbackFcn(app, @DataLoggingSwitchValueChanged, true);
            app.LoggingLampLabel = uilabel(app.UIFigure);
            app.LoggingLampLabel.Text = 'LoggingLamp';
            app.LoggingLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end